classdef SDMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LoadoptimizedparametersButton   matlab.ui.control.Button
        ExportsimulationsdataButton     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        IMSEPManrique2024Label          matlab.ui.control.Label
        GeneratereportButton            matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        PowerSpectralDensityGraphPanel  matlab.ui.container.Panel
        ImprovedFOMDesignVariablesLabel  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        RNNInferredDesignVariablesLabel  matlab.ui.control.Label
        DesignVariablesPanel            matlab.ui.container.Panel
        RuninferencetoperformarchitectureselectionLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SelectedArchitectureclicktoopenPanel  matlab.ui.container.Panel
        SpecificationsPanel             matlab.ui.container.Panel
        OutputSpecificationssimulationsPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        OptimizeButton                  matlab.ui.control.Button
        VerifyButton                    matlab.ui.control.Button
        TextArea_Powerwo                matlab.ui.control.TextArea
        TextArea_BWwo                   matlab.ui.control.TextArea
        TextArea_SNRwo                  matlab.ui.control.TextArea
        TextArea_Powerw                 matlab.ui.control.TextArea
        TextArea_BWw                    matlab.ui.control.TextArea
        TextArea_SNRw                   matlab.ui.control.TextArea
        wFOMimprovingLabel              matlab.ui.control.Label
        woFOMimprovLabel                matlab.ui.control.Label
        InputSpecificationsPanel        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PowermWEditField                matlab.ui.control.NumericEditField
        PowermWEditFieldLabel           matlab.ui.control.Label
        BWMHzEditField                  matlab.ui.control.NumericEditField
        BWMHzEditFieldLabel             matlab.ui.control.Label
        SNDRdBEditField                 matlab.ui.control.NumericEditField
        SNDRdBEditFieldLabel            matlab.ui.control.Label
        RuninferenceButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SigmaDeltaMAppLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        arch = [] % architecture
        column_names = [] %dvars names
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fullPath = mfilename('fullpath');
            [path, name, ext] = fileparts(fullPath);
            cd(path);
            folders = genpath('..');
            addpath(folders);
        end

        % Value changed function: SNDRdBEditField
        function SNDRdBEditFieldValueChanged(app, event)
            value = app.SNDRdBEditField.Value;
            app.SNDRdBEditField.Limits = [50,150];
        end

        % Value changed function: BWMHzEditField
        function BWMHzEditFieldValueChanged(app, event)
            value = app.BWMHzEditField.Value;
            app.BWMHzEditField.Limits = [0.01,100];
        end

        % Value changed function: PowermWEditField
        function PowermWEditFieldValueChanged(app, event)
            value = app.PowermWEditField.Value;
            app.PowermWEditField.Limits = [0.01,50];
        end

        % Button pushed function: RuninferenceButton
        function RuninferenceButtonPushed(app, event)

            app.RuninferenceButton.BackgroundColor = [0.87 0.60 0.60];

            % Inputs
            sndr = app.SNDRdBEditField.Value;
            assignin('base',"sndr",sndr)
            bw = 1e6*app.BWMHzEditField.Value;
            assignin('base',"bw",bw)
            save('bw.mat','bw')
            power = app.PowermWEditField.Value / 1e3;
            assignin('base',"power",power)

            fom = sndr + 10*log10(bw/power);
            input_specs = [fom,sndr,bw,power];
            inspecs_table = array2table(input_specs,'VariableNames',{'FOM','SNDR','BW','Power'});
            writetable(inspecs_table,'inspecs.csv')

            % Perform predictions
            pyarch = py.predictions.classifier_prediction(sndr,bw,power);
            py.predictions.rnn_prediction(sndr,bw,power,pyarch);
            dvars = readtable('dvars.csv');
            save('dvars.mat',"dvars")
            app.arch = string(pyarch);
            architecture = app.arch;
            save('arch.mat','architecture')
            %dvars = table(dfdvars);
            assignin('base',"dvars",dvars)

            % Selected architecture
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "on";
            app.RuninferencetoperformarchitectureselectionLabel.Text = app.arch;
            app.RuninferencetoperformarchitectureselectionLabel.FontColor = 'k';
            impath = strcat(app.arch,'.png');
            img = imread(impath);
            app.Image.Enable = "on";
            app.Image.ImageSource = img;
            
            % Predicted design vars:
            app.column_names = dvars.Properties.VariableNames;
            app.RNNInferredDesignVariablesLabel.Enable = "on";
            app.UITable.Enable = "on";
            app.UITable.ColumnName = app.column_names;
            app.UITable2.ColumnName = app.column_names; 
            app.UITable.Data = dvars;

            % Enable running simulations
            %app.RunsimulationsButton.Enable = "on";
            
            % Enable verify inferred design
            app.VerifyButton.Enable = "on";

            app.RuninferenceButton.BackgroundColor = [0.60 0.87 0.60];

        end

        % Callback function
        function RunsimulationsButtonPushed(app, event)

            % app.RunsimulationsButton.BackgroundColor = [0.87 0.60 0.60];
            % 
            % app.ImprovedFOMDesignVariablesLabel.Enable = "on";
            % 
            % 
            % %Run Random Variations script
            % run('randomvariations.m');
            % 
            % %Run simulations
            % sel_arch = app.arch;
            % sim_file = strcat('sim',sel_arch,'.m');
            % %gp_file = strcat(sel_arch,'_GP.mat');
            % %load(gp_file);
            % run(sim_file);
            % table_sim = readtable('total_sims.csv');
            % ann_row = table_sim(1,:);
            % opt_idx = find(table_sim.FOM == max(table_sim.FOM));
            % opt_row = table_sim(opt_idx,:);
            % 
            % sndr_ann = ann_row.SNDR;
            % sndr_opt = opt_row.SNDR;
            % bw_ann = ann_row.Bw;
            % bw_ann_mhz = bw_ann/1e6;
            % bw_opt = opt_row.Bw;
            % bw_opt_mhz = bw_opt/1e6;
            % power_ann = table_sim.Power;
            % power_ann_mw = power_ann*1000;
            % power_opt = opt_row.Power;
            % power_opt_mw = power_opt*1000;
            % 
            % app.TextArea_SNRwo.Enable = "on";
            % app.TextArea_SNRwo.Value = string(sndr_ann);
            % app.TextArea_SNRw.Enable = "on";
            % app.TextArea_SNRw.Value = string(sndr_opt);
            % app.TextArea_BWwo.Enable = "on";
            % app.TextArea_BWwo.Value = string(bw_ann_mhz);
            % app.TextArea_BWw.Enable = "on";
            % app.TextArea_BWw.Value = string(bw_opt_mhz);
            % app.TextArea_Powerwo.Enable = "on";
            % app.TextArea_Powerwo.Value = string(power_ann_mw);
            % app.TextArea_Powerw.Enable = "on";
            % app.TextArea_Powerw.Value = string(power_opt_mw);
            % 
            % dvars_names = app.column_names;
            % dvars_opt = opt_row(:,dvars_names);
            % app.UITable2.Data = dvars_opt;
            % 
            % app.wFOMimprovingLabel.Enable = "on";
            % app.woFOMimprovLabel.Enable = "on";
            % app.UITable2.Enable = "on";
            % app.GeneratereportButton.Enable = "on";
            % app.ExportsimulationsdataButton.Enable = "on";
            % app.Image2.Enable = "on";
            % app.Image2.ImageSource = 'figPSD.png';
            % 
            % 
            % app.RunsimulationsButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            app.UITable2.Enable = "on";
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SNDRdBEditField.Value = '';
            app.BWMHzEditField.Value = '';
            app.PowermWEditField.Value = '';

            app.woFOMimprovLabel.Enable = "off";
            app.wFOMimprovingLabel.Enable = "off";

            app.TextArea_SNRwo.Value = '';
            app.TextArea_SNRwo.Enable = "off";
            app.TextArea_BWwo.Value = '';
            app.TextArea_BWwo.Enable = "off";
            app.TextArea_Powerwo.Value = '';
            app.TextArea_Powerwo.Enable = "off";
            app.TextArea_SNRw.Value = '';
            app.TextArea_SNRw.Enable = "off";
            app.TextArea_BWw.Value = '';
            app.TextArea_BWw.Enable = "off";
            app.TextArea_Powerw.Value = '';
            app.TextArea_Powerw.Enable = "off";

            %app.RunsimulationsButton.BackgroundColor = [0.80 0.80 0.80];
            %app.RunsimulationsButton.Enable = "off";
            app.VerifyButton.BackgroundColor = [0.8 0.8 0.8];
            app.VerifyButton.Enable = "off";
            app.VerifyButton.Text = 'Verify';
            app.OptimizeButton.BackgroundColor = [0.8 0.8 0.8];
            app.OptimizeButton.Enable = "off";
            app.OptimizeButton.Text = 'Optimize';
            
            app.RuninferenceButton.BackgroundColor = [0.80 0.80 0.80];

            app.Image.Enable = "off";
            app.Image.ImageSource = '';
            app.Image2.Enable = "off";
            app.Image2.ImageSource = '';
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "off";
            
            app.UITable.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable.Enable = "off";
            app.UITable.Data = {'','','',''};

            app.UITable2.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable2.Enable = "off";
            app.UITable2.Data = {'','','',''};

            app.GeneratereportButton.Enable = "off";
            app.ExportsimulationsdataButton.Enable = "off";
            
            app.LoadoptimizedparametersButton.Enable = "off";

            close all
            
           
        end

        % Button pushed function: GeneratereportButton
        function GeneratereportButtonPushed(app, event)
            generate_report;
        end

        % Button pushed function: ExportsimulationsdataButton
        function ExportsimulationsdataButtonPushed(app, event)
            table_totsims = readtable('total_sims.csv');
            [file,location] = uiputfile('simsdata.csv','Save data');
            A = [file ~= 0, location ~= 0];

            if all(A)
                path = strcat(location,file);
                writetable(table_totsims,path)
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if app.Image.Enable == "on"
                model = app.arch;
                switch model
                    case '2ndGmCSDM'
                        GmC2ndCTSDMParam
                    case '2ndSCSDM_MultiBit'
                        SecondOrderMultibitSC
                    case '2ndSCSDM_SingleBit'
                        SecondOrderSingleBitSC
                    case '3or21SCSDM'
                        ThirdOrderCascadeSingleBitSC
                    case '4th211SCSDM'
                        umts211_real_PM1
                end
            end
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.VerifyButton.BackgroundColor = [0.87 0.60 0.60];
            app.VerifyButton.Text = 'Verifying...';

            %Run simulation
            sel_arch = app.arch;
            sim_file = strcat('verify',sel_arch,'.m');
            run(sim_file);
            table_sim = readtable('verification_results.csv');
            
            sndr = table_sim.SNDR;
            bw = table_sim.Bw;
            bw_mhz = bw/1e6;
            power = table_sim.Power;
            power_mw = power*1000;

            app.TextArea_SNRwo.Enable = "on";
            app.TextArea_SNRwo.Value = string(sndr);

            app.TextArea_BWwo.Enable = "on";
            app.TextArea_BWwo.Value = string(bw_mhz);

            app.TextArea_Powerwo.Enable = "on";
            app.TextArea_Powerwo.Value = string(power_mw);


            app.woFOMimprovLabel.Enable = "on";
            app.Image2.Enable = "on";
            app.Image2.ImageSource = 'figPSD_verification.png';


            app.VerifyButton.BackgroundColor = [0.60 0.87 0.60];
            app.VerifyButton.Text = 'Verify';

            app.OptimizeButton.Enable = "on";
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            app.OptimizeButton.BackgroundColor = [0.87 0.60 0.60];
            app.OptimizeButton.Text = 'Optimizing...';

            app.ImprovedFOMDesignVariablesLabel.Enable = "on";
            

            %Run Random Variations script
            run('randomvariations.m');

            %Run simulations
            sel_arch = app.arch;
            sim_file = strcat('optimize',sel_arch,'.m');
            run(sim_file);
            total_sim = readtable('total_sims.csv');
            ann_row = total_sim(1,:);
            opt_idx = find(total_sim.FOM == max(total_sim.FOM));
            opt_row = total_sim(opt_idx,:);
            
            fom_ann = ann_row.FOM;
            fom_opt = opt_row.FOM;


            sndr_opt = opt_row.SNDR;

            bw_opt = opt_row.Bw;
            bw_opt_mhz = bw_opt/1e6;

            power_opt = opt_row.Power;
            power_opt_mw = power_opt*1000;


            app.TextArea_SNRw.Enable = "on";
            app.TextArea_SNRw.Value = string(sndr_opt);

            app.TextArea_BWw.Enable = "on";
            app.TextArea_BWw.Value = string(bw_opt_mhz);

            app.TextArea_Powerw.Enable = "on";
            app.TextArea_Powerw.Value = string(power_opt_mw);

            
            dvars_opt = opt_row(:,5:end);
            app.UITable2.Data = dvars_opt;

            app.wFOMimprovingLabel.Enable = "on";

            app.UITable2.Enable = "on";
            app.GeneratereportButton.Enable = "on";
            app.ExportsimulationsdataButton.Enable = "on";
            app.Image2.Enable = "on";
            app.LoadoptimizedparametersButton.Enable = "on";

            if fom_opt > fom_ann    
                app.Image2.ImageSource = 'figPSD_optimization.png';
            end

            app.OptimizeButton.Text = 'Optimize';
            app.OptimizeButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Button pushed function: LoadoptimizedparametersButton
        function LoadoptimizedparametersButtonPushed(app, event)
            evalin('base','load("optimal_parameters.mat")')
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.Text = 'About';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SigmaDeltaMAppLabel = uilabel(app.GridLayout);
            app.SigmaDeltaMAppLabel.Interpreter = 'latex';
            app.SigmaDeltaMAppLabel.Text = '$\Sigma \Delta$M App';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.InputSpecificationsPanel = uipanel(app.GridLayout);
            app.InputSpecificationsPanel.Title = 'Input Specifications';
            app.GridLayout2 = uigridlayout(app.InputSpecificationsPanel);
            app.RuninferenceButton = uibutton(app.GridLayout2, 'push');
            app.RuninferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RuninferenceButtonPushed, true);
            app.RuninferenceButton.Text = 'Run inference';
            app.SNDRdBEditFieldLabel = uilabel(app.GridLayout2);
            app.SNDRdBEditFieldLabel.Text = 'SNDR (dB)';
            app.SNDRdBEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SNDRdBEditField.AllowEmpty = 'on';
            app.SNDRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNDRdBEditFieldValueChanged, true);
            app.BWMHzEditFieldLabel = uilabel(app.GridLayout2);
            app.BWMHzEditFieldLabel.Text = 'BW (MHz)';
            app.BWMHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BWMHzEditField.AllowEmpty = 'on';
            app.BWMHzEditField.ValueChangedFcn = createCallbackFcn(app, @BWMHzEditFieldValueChanged, true);
            app.PowermWEditFieldLabel = uilabel(app.GridLayout2);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PowermWEditField.AllowEmpty = 'on';
            app.PowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PowermWEditFieldValueChanged, true);
            app.OutputSpecificationssimulationsPanel = uipanel(app.GridLayout);
            app.OutputSpecificationssimulationsPanel.Title = 'Output Specifications (simulations)';
            app.GridLayout3 = uigridlayout(app.OutputSpecificationssimulationsPanel);
            app.woFOMimprovLabel = uilabel(app.GridLayout3);
            app.woFOMimprovLabel.Enable = 'off';
            app.woFOMimprovLabel.Text = 'w/o FOM improv.';
            app.wFOMimprovingLabel = uilabel(app.GridLayout3);
            app.wFOMimprovingLabel.Enable = 'off';
            app.wFOMimprovingLabel.Text = 'w/ FOM improving';
            app.TextArea_SNRw = uitextarea(app.GridLayout3);
            app.TextArea_SNRw.Enable = 'off';
            app.TextArea_BWw = uitextarea(app.GridLayout3);
            app.TextArea_BWw.Enable = 'off';
            app.TextArea_Powerw = uitextarea(app.GridLayout3);
            app.TextArea_Powerw.Enable = 'off';
            app.TextArea_SNRwo = uitextarea(app.GridLayout3);
            app.TextArea_SNRwo.Enable = 'off';
            app.TextArea_BWwo = uitextarea(app.GridLayout3);
            app.TextArea_BWwo.Enable = 'off';
            app.TextArea_Powerwo = uitextarea(app.GridLayout3);
            app.TextArea_Powerwo.Enable = 'off';
            app.VerifyButton = uibutton(app.GridLayout3, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Enable = 'off';
            app.VerifyButton.Text = 'Verify';
            app.OptimizeButton = uibutton(app.GridLayout3, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Enable = 'off';
            app.OptimizeButton.Text = 'Optimize';
            app.SpecificationsPanel = uipanel(app.GridLayout);
            app.SpecificationsPanel.Title = 'Specifications';
            app.SelectedArchitectureclicktoopenPanel = uipanel(app.GridLayout);
            app.SelectedArchitectureclicktoopenPanel.Title = 'Selected Architecture (click to open)';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.RuninferencetoperformarchitectureselectionLabel = uilabel(app.GridLayout);
            app.RuninferencetoperformarchitectureselectionLabel.Text = 'Run inference to perform architecture selection';
            app.DesignVariablesPanel = uipanel(app.GridLayout);
            app.DesignVariablesPanel.Title = 'Design Variables';
            app.RNNInferredDesignVariablesLabel = uilabel(app.GridLayout);
            app.RNNInferredDesignVariablesLabel.Text = 'RNN Inferred Design Variables:';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable2.RowName = {};
            app.UITable2.Enable = 'off';
            app.ImprovedFOMDesignVariablesLabel = uilabel(app.GridLayout);
            app.ImprovedFOMDesignVariablesLabel.Text = 'Improved FOM Design Variables:';
            app.PowerSpectralDensityGraphPanel = uipanel(app.GridLayout);
            app.PowerSpectralDensityGraphPanel.Title = 'Power Spectral Density Graph';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Enable = 'off';
            app.GeneratereportButton = uibutton(app.GridLayout, 'push');
            app.GeneratereportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratereportButtonPushed, true);
            app.GeneratereportButton.Enable = 'off';
            app.GeneratereportButton.Text = 'Generate report';
            app.IMSEPManrique2024Label = uilabel(app.GridLayout);
            app.IMSEPManrique2024Label.Text = 'IMSE - P. Manrique 2024';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExportsimulationsdataButton = uibutton(app.GridLayout, 'push');
            app.ExportsimulationsdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsimulationsdataButtonPushed, true);
            app.ExportsimulationsdataButton.Enable = 'off';
            app.ExportsimulationsdataButton.Text = 'Export simulations data';
            app.LoadoptimizedparametersButton = uibutton(app.GridLayout, 'push');
            app.LoadoptimizedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoptimizedparametersButtonPushed, true);
            app.LoadoptimizedparametersButton.Enable = 'off';
            app.LoadoptimizedparametersButton.Text = 'Load optimized parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end